classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LabelLabel                      matlab.ui.control.Label
        EnviarMicroButton               matlab.ui.control.Button
        SeleccionarCinematicaLabel      matlab.ui.control.Label
        SeleccionarCinematicaDropDown   matlab.ui.control.DropDown
        DetenerSimButton                matlab.ui.control.Button
        ConectarMicroButton             matlab.ui.control.Button
        ConectarSimButton               matlab.ui.control.Button
        AngulosLabel                    matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        DesconectarButton               matlab.ui.control.Button
        EnviarButton                    matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        VisualizacionBrazoRoboticoAxes  matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        Label2                          matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            % Obtener ángulos de los EditFields
            th1 = str2double(app.EditField.Value);
            th2 = str2double(app.EditField_2.Value);
            th3 = str2double(app.EditField_3.Value);
            th4 = str2double(app.EditField_4.Value);
            
            % Verificar qué cinemática está seleccionada
            if strcmp(app.SeleccionarCinematicaDropDown.Value, 'Directa')
                % Definir parámetros DH según tu robot
                L1 = 16.3; L2 = 14.994; L3 = 15.12; L4 = 15.5;
                alpha1 = 90;     a1 = 0;   d1 = L1;   theta1 = deg2rad(th1);
                alpha2 = 0;      a2 = L2;  d2 = 0;    theta2 = deg2rad(th2);
                alpha3 = 0;      a3 = L3;  d3 = 0;    theta3 = deg2rad(th3);
                alpha4 = 0;      a4 = L4;  d4 = 0;    theta4 = deg2rad(th4);
                
                % Calcular matrices de transformación directamente
                % Matriz T01
                T01 = [cos(theta1), -sin(theta1)*cos(alpha1), sin(theta1)*sin(alpha1), a1*cos(theta1);
                       sin(theta1), cos(theta1)*cos(alpha1), -cos(theta1)*sin(alpha1), a1*sin(theta1);
                       0, sin(alpha1), cos(alpha1), d1;
                       0, 0, 0, 1];
                   
                % Matriz T12
                T12 = [cos(theta2), -sin(theta2)*cos(alpha2), sin(theta2)*sin(alpha2), a2*cos(theta2);
                       sin(theta2), cos(theta2)*cos(alpha2), -cos(theta2)*sin(alpha2), a2*sin(theta2);
                       0, sin(alpha2), cos(alpha2), d2;
                       0, 0, 0, 1];
                   
                % Matriz T23
                T23 = [cos(theta3), -sin(theta3)*cos(alpha3), sin(theta3)*sin(alpha3), a3*cos(theta3);
                       sin(theta3), cos(theta3)*cos(alpha3), -cos(theta3)*sin(alpha3), a3*sin(theta3);
                       0, sin(alpha3), cos(alpha3), d3;
                       0, 0, 0, 1];
                   
                % Matriz T34
                T34 = [cos(theta4), -sin(theta4)*cos(alpha4), sin(theta4)*sin(alpha4), a4*cos(theta4);
                       sin(theta4), cos(theta4)*cos(alpha4), -cos(theta4)*sin(alpha4), a4*sin(theta4);
                       0, sin(alpha4), cos(alpha4), d4;
                       0, 0, 0, 1];
                
                % Calcular matrices compuestas
                T02 = T01 * T12;
                T03 = T02 * T23;
                T04 = T03 * T34;
                
                % Mostrar matriz T en el Label
                matStr = sprintf('Matriz T:\n[%.2f %.2f %.2f %.2f;\n %.2f %.2f %.2f %.2f;\n %.2f %.2f %.2f %.2f;\n %.2f %.2f %.2f %.2f]', ...
                    T04(1,1), T04(1,2), T04(1,3), T04(1,4), ...
                    T04(2,1), T04(2,2), T04(2,3), T04(2,4), ...
                    T04(3,1), T04(3,2), T04(3,3), T04(3,4), ...
                    T04(4,1), T04(4,2), T04(4,3), T04(4,4));
                app.Label2.Text = matStr;
                
                % Mostrar posición del efector final en el segundo Label
                pos = T04(1:3, 4);
                app.Label_5.Text = sprintf('Posición del efector final: X=%.2f, Y=%.2f, Z=%.2f', pos(1), pos(2), pos(3));
                
                % Visualizar el robot en el Axes
                cla(app.VisualizacionBrazoRoboticoAxes);  % Limpiar el axes
                
                % Obtener las posiciones de cada articulación
                O0 = [0; 0; 0];                % Origen
                O1 = T01(1:3, 4);              % Primera articulación
                O2 = T02(1:3, 4);              % Segunda articulación
                O3 = T03(1:3, 4);              % Tercera articulación
                O4 = T04(1:3, 4);              % Efector final
                
                % Configurar el axes
                hold(app.VisualizacionBrazoRoboticoAxes, 'on');
                grid(app.VisualizacionBrazoRoboticoAxes, 'on');
                axis(app.VisualizacionBrazoRoboticoAxes, 'equal');
                view(app.VisualizacionBrazoRoboticoAxes, 3);  % Vista 3D
                
                % Dibujar los eslabones
                plot3(app.VisualizacionBrazoRoboticoAxes, [O0(1), O1(1)], [O0(2), O1(2)], [O0(3), O1(3)], 'b-', 'LineWidth', 2);
                plot3(app.VisualizacionBrazoRoboticoAxes, [O1(1), O2(1)], [O1(2), O2(2)], [O1(3), O2(3)], 'r-', 'LineWidth', 2);
                plot3(app.VisualizacionBrazoRoboticoAxes, [O2(1), O3(1)], [O2(2), O3(2)], [O2(3), O3(3)], 'g-', 'LineWidth', 2);
                plot3(app.VisualizacionBrazoRoboticoAxes, [O3(1), O4(1)], [O3(2), O4(2)], [O3(3), O4(3)], 'm-', 'LineWidth', 2);
                
                % Dibujar las articulaciones
                scatter3(app.VisualizacionBrazoRoboticoAxes, O0(1), O0(2), O0(3), 50, 'k', 'filled');
                scatter3(app.VisualizacionBrazoRoboticoAxes, O1(1), O1(2), O1(3), 50, 'b', 'filled');
                scatter3(app.VisualizacionBrazoRoboticoAxes, O2(1), O2(2), O2(3), 50, 'r', 'filled');
                scatter3(app.VisualizacionBrazoRoboticoAxes, O3(1), O3(2), O3(3), 50, 'g', 'filled');
                scatter3(app.VisualizacionBrazoRoboticoAxes, O4(1), O4(2), O4(3), 50, 'm', 'filled');
                
                % Configurar límites del axes
                xlim(app.VisualizacionBrazoRoboticoAxes, [-40, 40]);
                ylim(app.VisualizacionBrazoRoboticoAxes, [-40, 40]);
                zlim(app.VisualizacionBrazoRoboticoAxes, [-40, 40]);
                
                hold(app.VisualizacionBrazoRoboticoAxes, 'off');
            else
                % Cinemática inversa (si se desea implementar)
                app.Label2.Text = 'Cinemática inversa aún no implementada';
            end
            
            % Si hay una conexión a la simulación, enviar los datos
            if isprop(app, 'ConexionSim') && app.ConexionSim
                % Enviar datos al workspace para la simulación
                assignin('base', 'th1_sim', rad2deg(th1));
                assignin('base', 'th2_sim', rad2deg(th2));
                assignin('base', 'th3_sim', rad2deg(th3));
                assignin('base', 'th4_sim', rad2deg(th4));
            end

        end

        % Button pushed function: EnviarMicroButton
        function EnviarMicroButtonPushed(app, event)
            % Verificar si la conexión está establecida
            if ~isprop(app, 'ConexionMicro') || ~app.ConexionMicro || ~isprop(app, 'ESP32Object') || isempty(app.ESP32Object)
                app.LabelLabel.Text = 'Error: ESP32 no conectada. Presiona "Conectar Micro" primero.';
                return;
            end
            
            % Obtener ángulos de los EditFields
            th1 = str2double(app.EditField.Value);
            th2 = str2double(app.EditField_2.Value);
            th3 = str2double(app.EditField_3.Value);
            th4 = str2double(app.EditField_4.Value);
            
            % Crear comando para enviar al ESP32
            comando = sprintf("M%d,%d,%d,%d\n", round(th1), round(th2), round(th3), round(th4));
            
            % Enviar comando a ESP32
            try
                writeline(app.ESP32Object, comando);
                app.Label2Label.Text = ['Enviado a ESP32: ' comando];
                
                % También ejecutar el cálculo de cinemática como en "Enviar"
                EnviarButtonPushed(app, event);
            catch e
                app.LabelLabel.Text = ['Error al enviar a ESP32: ' e.message];
            end
        end

        % Button pushed function: ConectarMicroButton
        function ConectarMicroButtonPushed(app, event)
            try
                % Añadir propiedad si no existe
                if ~isprop(app, 'ConexionMicro')
                    addprop(app, 'ConexionMicro');
                    app.ConexionMicro = false;
                end
                
                if ~isprop(app, 'ESP32Object')
                    addprop(app, 'ESP32Object');
                    app.ESP32Object = [];
                end
                
                % Buscar puertos disponibles
                ports = serialportlist;
                
                if isempty(ports)
                    app.LabelLabel.Text = 'Error: No hay puertos seriales disponibles';
                    return;
                end
                
                % Usar el primer puerto disponible
                selectedPort = ports(1);
                
                % Crear objeto de comunicación serial
                app.ESP32Object = serialport(selectedPort, 115200);
                app.ESP32Object.Timeout = 10;  % Timeout en segundos
                
                % Configurar terminación de línea
                configureTerminator(app.ESP32Object, "LF");
                
                % Esperar a que se establezca la conexión
                pause(2);
                
                % Actualizar estado
                app.ConexionMicro = true;
                app.LabelLabel.Text = ['Conectado a ESP32 en puerto ' char(selectedPort)];
            catch e
                app.LabelLabel.Text = ['Error al conectar: ' e.message];
                app.ConexionMicro = false;
            end
        end

        % Button pushed function: ConectarSimButton
        function ConectarSimButtonPushed(app, event)
            try
                % Añadir propiedad si no existe
                if ~isprop(app, 'ConexionSim')
                    addprop(app, 'ConexionSim');
                    app.ConexionSim = false;
                end
                
                % Inicializar variables en el workspace para la simulación
                assignin('base', 'th1_sim', str2double(app.EditField.Value));
                assignin('base', 'th2_sim', str2double(app.EditField_2.Value));
                assignin('base', 'th3_sim', str2double(app.EditField_3.Value));
                assignin('base', 'th4_sim', str2double(app.EditField_4.Value));
                
                % Intentar abrir el modelo Simscape (ajusta el nombre según tu modelo)
                modelName = 'RobotManipulador4DOF';
                
                % Verificar si el modelo ya está cargado
                if ~bdIsLoaded(modelName)
                    try
                        % Intentar cargar el modelo si existe
                        load_system(modelName);
                    catch
                        app.LabelLabel.Text = ['No se encontró el modelo "' modelName '". Asegúrate de que esté en el path de MATLAB.'];
                        return;
                    end
                end
                
                % Iniciar la simulación
                set_param(modelName, 'SimulationCommand', 'start');
                
                % Actualizar estado
                app.ConexionSim = true;
                app.LabelLabel.Text = 'Simulación conectada y en ejecución';
            catch e
                app.LabelLabel.Text = ['Error al conectar simulación: ' e.message];
                app.ConexionSim = false;
            end
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            % Desconectar ESP32 si está conectado
            if isprop(app, 'ConexionMicro') && app.ConexionMicro && isprop(app, 'ESP32Object') && ~isempty(app.ESP32Object)
                try
                    delete(app.ESP32Object);
                    app.ESP32Object = [];
                    app.ConexionMicro = false;
                catch
                    % Ignorar errores de desconexión
                end
            end
            
            % Detener simulación si está corriendo
            if isprop(app, 'ConexionSim') && app.ConexionSim
                try
                    modelName = 'RobotManipulador4DOF';
                    if bdIsLoaded(modelName)
                        set_param(modelName, 'SimulationCommand', 'stop');
                    end
                    app.ConexionSim = false;
                catch
                    % Ignorar errores al detener la simulación
                end
            end
            
            app.LabelLabel.Text = 'Desconectado de todos los sistemas';
        end

        % Button pushed function: DetenerSimButton
        function DetenerSimButtonPushed(app, event)
             try
                % Verificar si tenemos la propiedad
                if ~isprop(app, 'ConexionSim')
                    addprop(app, 'ConexionSim');
                    app.ConexionSim = false;
                end
                
                if app.ConexionSim
                    % Nombre del modelo (ajusta según tu modelo)
                    modelName = 'RobotManipulador4DOF';
                    
                    if bdIsLoaded(modelName)
                        set_param(modelName, 'SimulationCommand', 'stop');
                    end
                    
                    % Actualizar estado
                    app.ConexionSim = false;
                    app.LabelLabel.Text = 'Simulación detenida';
                else
                    app.LabelLabel.Text = 'No hay simulación conectada';
                end
            catch e
                app.LabelLabel.Text = ['Error al detener simulación: ' e.message];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x', 286};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EnviarButton = uibutton(app.LeftPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.DesconectarButton = uibutton(app.LeftPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'θ1';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'θ2';
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = 'θ3';
            app.EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = 'θ4';
            app.EditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.AngulosLabel = uilabel(app.LeftPanel);
            app.AngulosLabel.Text = 'Angulos';
            app.ConectarSimButton = uibutton(app.LeftPanel, 'push');
            app.ConectarSimButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarSimButtonPushed, true);
            app.ConectarSimButton.Text = 'Conectar Sim';
            app.ConectarMicroButton = uibutton(app.LeftPanel, 'push');
            app.ConectarMicroButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarMicroButtonPushed, true);
            app.ConectarMicroButton.Text = 'Conectar Micro';
            app.DetenerSimButton = uibutton(app.LeftPanel, 'push');
            app.DetenerSimButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerSimButtonPushed, true);
            app.DetenerSimButton.Text = 'Detener Sim';
            app.SeleccionarCinematicaDropDown = uidropdown(app.LeftPanel);
            app.SeleccionarCinematicaDropDown.Items = {'Directa', 'Inversa'};
            app.SeleccionarCinematicaDropDown.Value = 'Directa';
            app.SeleccionarCinematicaLabel = uilabel(app.LeftPanel);
            app.SeleccionarCinematicaLabel.Text = 'Seleccionar Cinematica';
            app.EnviarMicroButton = uibutton(app.LeftPanel, 'push');
            app.EnviarMicroButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarMicroButtonPushed, true);
            app.EnviarMicroButton.Text = 'EnviarMicro';
            app.LabelLabel = uilabel(app.LeftPanel);
            app.LabelLabel.Text = 'Info';
            app.CenterPanel = uipanel(app.GridLayout);
            app.VisualizacionBrazoRoboticoAxes = uiaxes(app.CenterPanel);
            title(app.VisualizacionBrazoRoboticoAxes, 'Visualización Brazo Robotico')
            xlabel(app.VisualizacionBrazoRoboticoAxes, 'X')
            ylabel(app.VisualizacionBrazoRoboticoAxes, 'Y')
            zlabel(app.VisualizacionBrazoRoboticoAxes, 'Z')
            app.VisualizacionBrazoRoboticoAxes.XLim = [-40 40];
            app.VisualizacionBrazoRoboticoAxes.YLim = [-40 40];
            app.VisualizacionBrazoRoboticoAxes.ZLim = [-40 40];
            app.VisualizacionBrazoRoboticoAxes.XTick = [-40 -30 -20 -10 0 10 20 30 40];
            app.RightPanel = uipanel(app.GridLayout);
            app.Label_5 = uilabel(app.RightPanel);
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end